classdef Reach_explorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        ExploreTab                matlab.ui.container.Tab
        Thing2DropDown            matlab.ui.control.DropDown
        Thing2DropDownLabel       matlab.ui.control.Label
        Plot2thingsCheckBox       matlab.ui.control.CheckBox
        SavePathEditField         matlab.ui.control.EditField
        SavePathEditFieldLabel    matlab.ui.control.Label
        SavemeButton              matlab.ui.control.Button
        Thing1DropDown            matlab.ui.control.DropDown
        Thing1DropDownLabel       matlab.ui.control.Label
        ReachExplorerLabel        matlab.ui.control.Label
        UITable2                  matlab.ui.control.Table
        LoadButton                matlab.ui.control.Button
        DFPathEditField           matlab.ui.control.EditField
        DFPathEditFieldLabel      matlab.ui.control.Label
        ReachDropDown             matlab.ui.control.DropDown
        ReachDropDownLabel        matlab.ui.control.Label
        TrainingDayDropDown       matlab.ui.control.DropDown
        TrainingDayDropDownLabel  matlab.ui.control.Label
        MouseIDDropDown           matlab.ui.control.DropDown
        MouseIDDropDownLabel      matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        MetaTab                   matlab.ui.container.Tab
        UITable                   matlab.ui.control.Table
    end

    
    properties (Access = private)
       meta_table
       df
       day
       mouse
       day_df
       reach_df
       reach
       output
       % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            df=load(app.DFPathEditField.Value);
            df=df.metaBIGdf;
            mouse_list=unique(df.MouseID);
            meta_table=table('Size',[height(mouse_list),5],'VariableTypes',{'string','string','string','double','double'});
            for i=1:height(mouse_list)
                meta_table(i,:)=df(find(strcmp(df.MouseID, mouse_list{i}),1,'last'),[1 2 3 6 7]);
            end
            app.UITable.Data = meta_table;
            app.MouseIDDropDown.Items = mouse_list;
            app.meta_table=meta_table;
            app.df=df;
        end

        % Value changed function: MouseIDDropDown
        function MouseIDDropDownValueChanged(app, event)
            value = app.MouseIDDropDown.Value;
            app.mouse=value;
            DayNumber=app.meta_table.Var4(strcmp(app.meta_table.Var1,value));
            app.TrainingDayDropDown.Items=string(num2cell([1:DayNumber]))';
            
%             plot(app.UIAxes,data,other_options)
        end

        % Callback function
        function ReachDropDownOpening(app, event)
            
        end

        % Drop down opening function: ReachDropDown
        function ReachDropDownValueChanged(app, event)
            value = app.ReachDropDown.Value;
            app.reach=str2num(value);
            temp_df=app.day_df(app.reach,:);
            varList={'filename','outcome','duration','reach_length',...
                'TrajectoryX','TrajectoryY','TrajectoryZ','VelocityX',...
                'VelocityY','VelocityZ','VelocityAbs','ConfXY','ConfZ'};
            app.reach_df=table(temp_df.filename,string(temp_df.behaviors{1}),...
                ((temp_df.reachEnd-temp_df.reachInit)/150),...
                temp_df.handArcLength{1}/900,{temp_df.DTWHandNorm{1}(:,1)/900},...
                {(temp_df.DTWHandNorm{1}(:,2)/900)*-1},{temp_df.DTWHandNorm{1}(:,3)/900},...
                {(temp_df.velInt{1}(:,1)/900)*150},{(temp_df.velInt{1}(:,2)/900)*150},...
                {(temp_df.velInt{1}(:,3)/900)*150},{(temp_df.velAbs{1}(:,1)/900)*150},...
                {temp_df.handSideConf{1}/100},{temp_df.handFrontConf{1}/100},...
                'VariableNames',varList);
            app.UITable2.Data=app.reach_df(1,1:4);
            if app.reach==69
                app.UIAxes.Title.String='Nice';
            else
                app.UIAxes.Title.String='Title';
            end
            app.Thing1DropDown.Items = varList(5:end)';
        end

        % Value changed function: TrainingDayDropDown
        function TrainingDayDropDownValueChanged(app, event)
            value = app.TrainingDayDropDown.Value;
            app.day=str2num(value);
            ReachNumber=height(app.df(app.df.MouseID==app.mouse & app.df.day==str2num(value),:));
            app.ReachDropDown.Items=string(num2cell([1:ReachNumber]))';
            app.day_df=app.df(app.df.MouseID==app.mouse & app.df.day==str2num(value),:);
        end

        % Value changed function: Thing1DropDown
        function Thing1DropDownValueChanged(app, event)
            value = app.Thing1DropDown.Value;
            tempPlotData=app.reach_df.(value){1};
            
            app.output=plot(app.UIAxes,tempPlotData);
            app.UIAxes.Title.String=value;
            L=legend(app.UIAxes,app.reach_df.outcome,'Interpreter','none','Location','best');
            if contains(value,'Conf')
                app.UIAxes.XLabel.String='Frame#';
            else
                app.UIAxes.XLabel.String='Normalized Time (AU)';
            end
            if contains(value,'Traj')
                app.UIAxes.YLabel.String='Distance from pellet (mm)';
            elseif contains(value,'Vel')
                app.UIAxes.YLabel.String='mm/s';
            else
                app.UIAxes.YLabel.String='Tracking Confidence (%)';
            end
            
            if app.Plot2thingsCheckBox.Value==1
                app.Thing2DropDown.Items = app.reach_df.Properties.VariableNames(...
                    contains(app.reach_df.Properties.VariableNames,app.Thing1DropDown.Value(1:4)))';
            end
        end

        % Button pushed function: SavemeButton
        function SavemeButtonPushed(app, event)
            save_path=app.SavePathEditField.Value;
            exportgraphics(app.UIAxes,strcat(save_path,'\',app.mouse,'Day',...
                num2str(app.day),'Reach',num2str(app.reach),app.UIAxes.Title.String,'.png'));
%             saveas(app.output,strcat(save_path,'\',app.mouse,'Day',num2str(app.day),'Reach',num2str(app.reach)),'png');

        end

        % Value changed function: Plot2thingsCheckBox
        function Plot2thingsCheckBoxValueChanged(app, event)
            value = app.Plot2thingsCheckBox.Value;
            if value==1                
                app.Thing2DropDown.Items = app.reach_df.Properties.VariableNames(...
                    contains(app.reach_df.Properties.VariableNames,app.Thing1DropDown.Value(1:4)))';
            else
                app.Thing2DropDown.Items ="Ignore me";
            end
        end

        % Value changed function: Thing2DropDown
        function Thing2DropDownValueChanged(app, event)
            value = app.Thing2DropDown.Value;
            thing2data=app.reach_df.(value){1};
            thing1data=app.reach_df.(app.Thing1DropDown.Value){1};
            
            app.output=plot(app.UIAxes,thing1data,thing2data);
            app.UIAxes.Title.String=strcat(app.Thing1DropDown.Value,'&',value(end));
            L=legend(app.UIAxes,app.reach_df.outcome,'Interpreter','none','Location','best');
            
            if contains(value,'Traj')
                app.UIAxes.YLabel.String=strcat(value(end),'Distance from pellet (mm)');
                app.UIAxes.XLabel.String=strcat(app.Thing1DropDown.Value(end),'Distance from pellet (mm)');
            elseif contains(value,'Vel')
                app.UIAxes.YLabel.String=strcat(value(end),'mm/s');
                app.UIAxes.XLabel.String=strcat(app.Thing1DropDown.Value(end),'mm/s');
            else
                app.UIAxes.YLabel.String=strcat(value(end),'Tracking Confidence (%)');
                app.UIAxes.YLabel.String=strcat(app.Thing1DropDown.Value(end),'Tracking Confidence (%)');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ExploreTab = uitab(app.TabGroup);
            app.ExploreTab.Title = 'Explore';
            app.UIAxes = uiaxes(app.ExploreTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MouseIDDropDownLabel = uilabel(app.ExploreTab);
            app.MouseIDDropDownLabel.Text = 'Mouse ID';
            app.MouseIDDropDown = uidropdown(app.ExploreTab);
            app.MouseIDDropDown.Items = {'Bring back'};
            app.MouseIDDropDown.ValueChangedFcn = createCallbackFcn(app, @MouseIDDropDownValueChanged, true);
            app.MouseIDDropDown.Value = 'Bring back';
            app.TrainingDayDropDownLabel = uilabel(app.ExploreTab);
            app.TrainingDayDropDownLabel.Text = 'Training Day';
            app.TrainingDayDropDown = uidropdown(app.ExploreTab);
            app.TrainingDayDropDown.Items = {'the beard'};
            app.TrainingDayDropDown.ValueChangedFcn = createCallbackFcn(app, @TrainingDayDropDownValueChanged, true);
            app.TrainingDayDropDown.Value = 'the beard';
            app.ReachDropDownLabel = uilabel(app.ExploreTab);
            app.ReachDropDownLabel.Text = 'Reach';
            app.ReachDropDown = uidropdown(app.ExploreTab);
            app.ReachDropDown.Items = {'Dan'};
            app.ReachDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ReachDropDownValueChanged, true);
            app.ReachDropDown.Value = 'Dan';
            app.DFPathEditFieldLabel = uilabel(app.ExploreTab);
            app.DFPathEditFieldLabel.Text = 'DF Path';
            app.DFPathEditField = uieditfield(app.ExploreTab, 'text');
            app.DFPathEditField.Value = 'C:\Users\sgkb1\Documents\Matlab\CLARA_Vectors\ExampleDFmeta.mat';
            app.LoadButton = uibutton(app.ExploreTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.UITable2 = uitable(app.ExploreTab);
            app.UITable2.ColumnName = {'Filename'; 'Reach Type'; 'Duration (s)'; 'Reach Length (mm)'};
            app.UITable2.RowName = {};
            app.ReachExplorerLabel = uilabel(app.ExploreTab);
            app.ReachExplorerLabel.Text = 'Reach Explorer';
            app.Thing1DropDownLabel = uilabel(app.ExploreTab);
            app.Thing1DropDownLabel.Text = 'Thing 1';
            app.Thing1DropDown = uidropdown(app.ExploreTab);
            app.Thing1DropDown.Items = {'42'};
            app.Thing1DropDown.ValueChangedFcn = createCallbackFcn(app, @Thing1DropDownValueChanged, true);
            app.Thing1DropDown.Value = '42';
            app.SavemeButton = uibutton(app.ExploreTab, 'push');
            app.SavemeButton.ButtonPushedFcn = createCallbackFcn(app, @SavemeButtonPushed, true);
            app.SavemeButton.Text = 'Save me';
            app.SavePathEditFieldLabel = uilabel(app.ExploreTab);
            app.SavePathEditFieldLabel.Text = 'Save Path';
            app.SavePathEditField = uieditfield(app.ExploreTab, 'text');
            app.SavePathEditField.Value = 'C:\Users\sgkb1\Documents\NRSC7657\Spencer_B';
            app.Plot2thingsCheckBox = uicheckbox(app.ExploreTab);
            app.Plot2thingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @Plot2thingsCheckBoxValueChanged, true);
            app.Plot2thingsCheckBox.Text = 'Plot 2 things';
            app.Thing2DropDownLabel = uilabel(app.ExploreTab);
            app.Thing2DropDownLabel.Text = 'Thing 2';
            app.Thing2DropDown = uidropdown(app.ExploreTab);
            app.Thing2DropDown.Items = {'Ignore me'};
            app.Thing2DropDown.ValueChangedFcn = createCallbackFcn(app, @Thing2DropDownValueChanged, true);
            app.Thing2DropDown.Value = 'Ignore me';
            app.MetaTab = uitab(app.TabGroup);
            app.MetaTab.Title = 'Meta';
            app.UITable = uitable(app.MetaTab);
            app.UITable.ColumnName = {'Mouse ID'; 'Cohort'; 'Example Meta'; 'Last Training Day'; 'Final Performance (%)'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Reach_explorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end